classdef VL53L5CX_MatlabBT_tercera_prueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Matriz_Distancia  matlab.ui.Figure
        cmdControl        matlab.ui.control.StateButton
        pltEstado         matlab.ui.control.UIAxes
        pltOriginal       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bt % Puerto Bluetooth
        running = false
    end
    
    methods (Access = private)
        
        function Funcion_Recepcion(app,~,~)
            Datos=read(app.bt,160);
            i=1;j=1;
            y=zeros(1,40); % y es el vector de distancia
            s=zeros(1,40); % s es el vector de estado
            while i<=80
                y(j)=256*Datos(i)+Datos(i+1);
                i=i+2; j=j+1;
            end
            i=81; j=1;
            while (i>=81 && i<=160)
                s(j)=256*Datos(i)+Datos(i+1);
                if (s(j) == 5)
                    s(j)=1000;
                else
                    s(j)=0;
                end
                i=i+2; j=j+1;
            end
            %disp(y);
            Y = reshape(y,[8,5]); % Y es la matriz de distancia 5 x 8
            Y=Y'; % Matriz traspuesta
            Y = rot90(Y,2);
            
            Y(Y > 800) = 800;

            S = reshape(s,[8,5]); % S es la matriz de estado 8 x 8
            S=S'; % Matriz traspuesta
            S = rot90(S,2);
            %disp(S);
            Yimg = mat2gray(Y,[0 800]); % Transf. Niveles de grises. 0 negro y 800 blanco
            Simg = mat2gray(S,[0 1000]); % Transf. Niveles de grises. 0 negro y 1000 blanco
            imshow(Yimg,'Parent',app.pltOriginal);
            imshow(Simg,'Parent',app.pltEstado);
            
            % Y: matriz 5x8 (mm)
            angles = [-24.0625  -17.1875  -10.3125  -3.4375  ...
                       3.4375   10.3125   17.1875   24.0625];

            k = 3;

        distMean = zeros(1,8);

for c = 1:8
    x = Y(:,c);

    med  = median(x);
    madv = median(abs(x - med));
      
        x_f = zeros(1,5);
    if madv == 0
        x_f = x;
    else
        for r = 1:5
            variant = abs(x(r) - med);
            if variant <= k*madv
                x_f(r) = x(r);
            else
                x_f(r) = 0;
            end
        end
    end

    valid = x_f(x_f ~= 0);
    if isempty(valid)
        distMean(c) = 0;
    else
        distMean(c) = mean(valid);
    end
end

      [~, idx] = max(distMean);
        
            
              % ---------------- VELOCIDAD BASE ----------------
            % zona frontal
            d4 = distMean(4);
            d5 = distMean(5);

            if d4 > 0 && d5 > 0
               d_front = min(d4, d5);
            elseif d4 > 0
               d_front = d4;
            elseif d5 > 0
               d_front = d5;
            else
               d_front = 0;
            end

            if d_front <= 500
               speed = 0;
            elseif d_front >= 700
               speed = 70;
            else   
               speed = round((d_front - 500) / 200 * 70);
            end


            persistent avoiding
            if isempty(avoiding)
                avoiding = false;
            end
            
            if d_front <= 600
                avoiding = true;      % estoy evitando
                write(app.bt, uint8([4 idx]))
            
            elseif d_front >= 600 && avoiding
                write(app.bt, [6 0])  % vuelvo al rumbo inicial
                avoiding = false;     % ya no evito
            end

              % Enviar velocidad a Arduino
            write(app.bt, uint8([5 speed]));

           
            if app.running
               write(app.bt,uint8([3 0]));
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc;
            app.bt=bluetooth("ESP32BT_Rover4",1); % device = bluetooth(name,channel)
            configureCallback(app.bt,"byte",160,@app.Funcion_Recepcion);
            
            app.cmdControl.Text='Inicio'; % Se cambia el texto del botón a "Paro"

        end

        % Value changed function: cmdControl
        function cmdControlValueChanged(app, event)
            
            valor = app.cmdControl.Value;

            if valor==1
               app.running = true;

                % START + fijar referencia inicial
                write(app.bt, uint8([1 0]));

                % Primera petición de ToF
                write(app.bt, uint8([3 0]));
               
                app.cmdControl.Text='Paro';
            else
               app.running = false;
               write(app.bt, uint8([2 0]));
               app.cmdControl.Text='Inicio';
             end

        end

        % Close request function: Matriz_Distancia
        function Matriz_DistanciaCloseRequest(app, event)

            app.running = false;
            configureCallback(app.bt,"off");
            clear app.bt;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Matriz_Distancia = uifigure('Visible', 'off');
            app.Matriz_Distancia.Name = 'Matriz de Distancia VL53L5CX';
            app.Matriz_Distancia.CloseRequestFcn = createCallbackFcn(app, @Matriz_DistanciaCloseRequest, true);
            app.pltOriginal = uiaxes(app.Matriz_Distancia);
            title(app.pltOriginal, 'DISTANCIA')
            app.pltEstado = uiaxes(app.Matriz_Distancia);
            title(app.pltEstado, 'ESTADO')
            app.cmdControl = uibutton(app.Matriz_Distancia, 'state');
            app.cmdControl.ValueChangedFcn = createCallbackFcn(app, @cmdControlValueChanged, true);
            app.cmdControl.Text = 'Control';
            % Show the figure after all components are created
            app.Matriz_Distancia.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VL53L5CX_MatlabBT_tercera_prueba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Matriz_Distancia)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Matriz_Distancia)
        end
    end
end